classdef multiPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TypeofGraphButtonGroup   matlab.ui.container.ButtonGroup
        plotButton               matlab.ui.control.RadioButton
        cometButton              matlab.ui.control.RadioButton
        stemButton               matlab.ui.control.RadioButton
        stepButton               matlab.ui.control.RadioButton
        FunctionsofXButtonGroup  matlab.ui.container.ButtonGroup
        sineButton               matlab.ui.control.RadioButton
        cosineButton             matlab.ui.control.RadioButton
        tangentButton            matlab.ui.control.RadioButton
        logarithmButton          matlab.ui.control.RadioButton
        exponentialButton        matlab.ui.control.RadioButton
        squarerootButton         matlab.ui.control.RadioButton
        GraphButton              matlab.ui.control.Button
        ValuesofXEditFieldLabel  matlab.ui.control.Label
        ValuesofXEditField       matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
        randomValsButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: randomValsButton
        function randomValsButtonPushed(app, event)
            yourXValues=sort(rand(1,100)*100);%rand generates values between 0 and 1
            app.ValuesofXEditField.Value=''; 
           app.ValuesofXEditField.Value=num2str(yourXValues);
            
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
           vals=str2num(app.ValuesofXEditField.Value);
           cla(app.UIAxes);
            if(app.plotButton.Value==true)
               
              if(app.sineButton.Value==true)
                 plot(vals,sin(vals),'Parent',app.UIAxes);
              elseif(app.cosineButton.Value==true)
                 plot(vals,cos(vals),'Parent',app.UIAxes);
              elseif(app.tangentButton.Value==true)
                  plot(vals,tan(vals),'Parent',app.UIAxes);
              elseif(app.logarithmButton.Value==true)
                  plot(vals,log(vals),'Parent',app.UIAxes);
              elseif(app.exponentialButton.Value==true)
                  plot(vals,exp(vals),'Parent',app.UIAxes);
              elseif(app.squarerootButton.Value==true)
                  plot(vals,sqrt(vals),'Parent',app.UIAxes);
              end  
              
            elseif(app.cometButton.Value==true)
               if(app.sineButton.Value==true)
                 comet(vals,sin(vals),'Parent',app.UIAxes);
              elseif(app.cosineButton.Value==true)
                 comet(vals,cos(vals),'Parent',app.UIAxes);
              elseif(app.tangentButton.Value==true)
                  comet(vals,tan(vals),'Parent',app.UIAxes);
              elseif(app.logarithmButton.Value==true)
                  comet(vals,log(vals),'Parent',app.UIAxes);
              elseif(app.exponentialButton.Value==true)
                 comet(vals,exp(vals),'Parent',app.UIAxes);
              elseif(app.squarerootButton.Value==true)
                  comet(vals,sqrt(vals),'Parent',app.UIAxes);
               end  
               
            elseif(app.stemButton.Value==true)
               if(app.sineButton.Value==true)
                 stem(vals,sin(vals),'Parent',app.UIAxes);
              elseif(app.cosineButton.Value==true)
                  stem(vals,cos(vals),'Parent',app.UIAxes);
              elseif(app.tangentButton.Value==true)
                   stem(vals,tan(vals),'Parent',app.UIAxes);
              elseif(app.logarithmButton.Value==true)
                   stem(vals,log(vals),'Parent',app.UIAxes);
              elseif(app.exponentialButton.Value==true)
                  stem(vals,exp(vals),'Parent',app.UIAxes);
              elseif(app.squarerootButton.Value==true)
                   stem(vals,sqrt(vals),'Parent',app.UIAxes);
              end  
            elseif(app.stepButton.Value==true)
               if(app.sineButton.Value==true)
                 stairs(vals,sin(vals),'Parent',app.UIAxes);
              elseif(app.cosineButton.Value==true)
                  stairs(vals,cos(vals),'Parent',app.UIAxes);
              elseif(app.tangentButton.Value==true)
                   stairs(vals,tan(vals),'Parent',app.UIAxes);
              elseif(app.logarithmButton.Value==true)
                   stairs(vals,log(vals),'Parent',app.UIAxes);
              elseif(app.exponentialButton.Value==true)
                  stairs(vals,exp(vals),'Parent',app.UIAxes);
              elseif(app.squarerootButton.Value==true)
                   stairs(vals,sqrt(vals),'Parent',app.UIAxes);
              end  
            end
           
            xlabel('xvalues','Parent',app.UIAxes);
            ylabel('yvalues','Parent',app.UIAxes);
            title('TH Rosenheim','Parent',app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TypeofGraphButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeofGraphButtonGroup.Title = 'Type of Graph';
            app.plotButton = uiradiobutton(app.TypeofGraphButtonGroup);
            app.plotButton.Text = 'plot';
            app.plotButton.Value = true;
            app.cometButton = uiradiobutton(app.TypeofGraphButtonGroup);
            app.cometButton.Text = 'comet';
            app.stemButton = uiradiobutton(app.TypeofGraphButtonGroup);
            app.stemButton.Text = 'stem';
            app.stepButton = uiradiobutton(app.TypeofGraphButtonGroup);
            app.stepButton.Text = 'step';
            app.FunctionsofXButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionsofXButtonGroup.Title = 'Functions of X';
            app.sineButton = uiradiobutton(app.FunctionsofXButtonGroup);
            app.sineButton.Text = 'sine';
            app.sineButton.Value = true;
            app.cosineButton = uiradiobutton(app.FunctionsofXButtonGroup);
            app.cosineButton.Text = 'cosine';
            app.tangentButton = uiradiobutton(app.FunctionsofXButtonGroup);
            app.tangentButton.Text = 'tangent';
            app.logarithmButton = uiradiobutton(app.FunctionsofXButtonGroup);
            app.logarithmButton.Text = 'logarithm';
            app.exponentialButton = uiradiobutton(app.FunctionsofXButtonGroup);
            app.exponentialButton.Text = 'exponential';
            app.squarerootButton = uiradiobutton(app.FunctionsofXButtonGroup);
            app.squarerootButton.Text = 'square root';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.ValuesofXEditFieldLabel = uilabel(app.UIFigure);
            app.ValuesofXEditFieldLabel.Text = 'Values of X';
            app.ValuesofXEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.randomValsButton = uibutton(app.UIFigure, 'push');
            app.randomValsButton.ButtonPushedFcn = createCallbackFcn(app, @randomValsButtonPushed, true);
            app.randomValsButton.Text = 'random Vals';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end